classdef splinecurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        x1EditFieldLabel         matlab.ui.control.Label
        x1EditField              matlab.ui.control.NumericEditField
        y1EditFieldLabel         matlab.ui.control.Label
        y1EditField              matlab.ui.control.NumericEditField
        x2EditFieldLabel         matlab.ui.control.Label
        x2EditField              matlab.ui.control.NumericEditField
        y2EditFieldLabel         matlab.ui.control.Label
        y2EditField              matlab.ui.control.NumericEditField
        x3EditFieldLabel         matlab.ui.control.Label
        x3EditField              matlab.ui.control.NumericEditField
        y3EditFieldLabel         matlab.ui.control.Label
        y3EditField              matlab.ui.control.NumericEditField
        coeftable                matlab.ui.control.Table
        runbutton                matlab.ui.control.Button
        ShowNodesButton          matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        Equation1EditFieldLabel  matlab.ui.control.Label
        Equation1EditField       matlab.ui.control.EditField
        Equation2EditFieldLabel  matlab.ui.control.Label
        Equation2EditField       matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        x1 = 0
        y1 = 0
        x2 = 0
        y2 = 0
        x3 = 0
        y3 = 0
        coefs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runbutton
        function runbuttonButtonPushed(app, event)
            syms a b c d e f g h 
            
            app.x1 = app.x1EditField.Value;
            app.y1 = app.y1EditField.Value;
            app.x2 = app.x2EditField.Value;
            app.y2 = app.y2EditField.Value;
            app.x3 = app.x3EditField.Value;
            app.y3 = app.y3EditField.Value;
            
            eqns = [ a * app.x2^3 + b * app.x2^2 + c*app.x2 + d == app.y2,
                     e * app.x2^3 + f * app.x2^2 + g*app.x2 + h == app.y2,
                     a * app.x1^3 + b * app.x1^2 + c*app.x1 + d == app.y1,
                     e * app.x3^3 + f * app.x3^2 + g*app.x3 + h == app.y3,
                     3 * a * app.x2^2 + 2 * b * app.x2 + c == 3 * e * app.x2^2 + 2 * f * app.x2 + g,
                     6 * a * app.x2 + 2 * b == 6 * e * app.x2 + 2 * f,
                     6 * a * app.x1 + 2 * b == 0,
                     6 * e * app.x3 + 2 * f == 0];
                 
            vars = [a,b,c,d,e,f,g,h];
            
            [Matrixformeqns,value] = equationsToMatrix(eqns,vars);
            
            app.coefs = inv(Matrixformeqns) * value;
            
            app.coeftable.Data = double(app.coefs);
            
            q1 = app.coefs(1);
            q2 = app.coefs(2);
            q3 = app.coefs(3);
            q4 = app.coefs(4);
            q5 = app.coefs(5);
            q6 = app.coefs(6);
            q7 = app.coefs(7);
            q8 = app.coefs(8);
            
            hold(app.UIAxes,"on")  ;
            fplot(app.UIAxes, @(x) q1*x^3 + q2*x^2 + q3*x + q4, [app.x1,app.x2],'b');
            fplot(app.UIAxes, @(x) q5*x^3 + q6*x^2 + q7*x + q8, [app.x2,app.x3],'r');
            s1 = strcat(char(q1), "*x^3+", char(q2), "*x^2+", char(q3), "*x+", char(q4));
            app.Equation1EditField.Value = s1;
            s2 = strcat(char(q5), "*x^3+", char(q6), "*x^2+", char(q7), "*x+", char(q8));
            app.Equation2EditField.Value = s2;
 
        end

        % Button pushed function: ShowNodesButton
        function ShowNodesButtonPushed(app, event)
            app.x1 = app.x1EditField.Value;
            app.y1 = app.y1EditField.Value;
            app.x2 = app.x2EditField.Value;
            app.y2 = app.y2EditField.Value;
            app.x3 = app.x3EditField.Value;
            app.y3 = app.y3EditField.Value;
            hold(app.UIAxes,"on")  ;
            sz = 100;
            scatter(app.UIAxes, app.x1,app.y1,sz,'filled');
            scatter(app.UIAxes, app.x2,app.y2,sz,'filled');
            scatter(app.UIAxes, app.x3,app.y3,sz,'filled');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.x1 = 0;
            app.y1 = 0;
            app.x2 = 0;
            app.y2 = 0;
            app.x3 = 0;
            app.y3 = 0;
            app.coefs = [];
            app.x1EditField.Value = 0;
            app.y1EditField.Value = 0;
            app.x2EditField.Value = 0;
            app.y2EditField.Value = 0;
            app.x3EditField.Value = 0;
            app.y3EditField.Value = 0;
            cla(app.UIAxes);
            app.coeftable.Data  = [];
            app.Equation1EditField.Value = "";
            app.Equation2EditField.Value = "";

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.x1EditFieldLabel = uilabel(app.GridLayout);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.GridLayout, 'numeric');
            app.y1EditFieldLabel = uilabel(app.GridLayout);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.GridLayout, 'numeric');
            app.x2EditFieldLabel = uilabel(app.GridLayout);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.GridLayout, 'numeric');
            app.y2EditFieldLabel = uilabel(app.GridLayout);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.GridLayout, 'numeric');
            app.x3EditFieldLabel = uilabel(app.GridLayout);
            app.x3EditFieldLabel.Text = 'x3';
            app.x3EditField = uieditfield(app.GridLayout, 'numeric');
            app.y3EditFieldLabel = uilabel(app.GridLayout);
            app.y3EditFieldLabel.Text = 'y3';
            app.y3EditField = uieditfield(app.GridLayout, 'numeric');
            app.coeftable = uitable(app.GridLayout);
            app.coeftable.ColumnName = {'Coefficients'};
            app.coeftable.RowName = {};
            app.runbutton = uibutton(app.GridLayout, 'push');
            app.runbutton.ButtonPushedFcn = createCallbackFcn(app, @runbuttonButtonPushed, true);
            app.runbutton.Text = 'RUN';
            app.ShowNodesButton = uibutton(app.GridLayout, 'push');
            app.ShowNodesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowNodesButtonPushed, true);
            app.ShowNodesButton.Text = 'Show Nodes';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Equation1EditFieldLabel = uilabel(app.GridLayout);
            app.Equation1EditFieldLabel.Text = 'Equation1';
            app.Equation1EditField = uieditfield(app.GridLayout, 'text');
            app.Equation1EditField.Editable = 'off';
            app.Equation2EditFieldLabel = uilabel(app.GridLayout);
            app.Equation2EditFieldLabel.Text = 'Equation2';
            app.Equation2EditField = uieditfield(app.GridLayout, 'text');
            app.Equation2EditField.Editable = 'off';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = splinecurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end