classdef softroboticsmassspringdamper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ForceProfileXLabel             matlab.ui.control.Label
        ForceProfileXSwitch            matlab.ui.control.Switch
        ForceProfileYLabel             matlab.ui.control.Label
        ForceProfileYSwitch            matlab.ui.control.Switch
        ForceMagnifierforXSliderLabel  matlab.ui.control.Label
        ForceMagnifierforXSlider       matlab.ui.control.Slider
        ForceMagnifierforYSlider       matlab.ui.control.Slider
        ForceMagnifierforYLabel        matlab.ui.control.Label
        TimeStepBetweenIterationsEditFieldLabel  matlab.ui.control.Label
        TimeStepEditField              matlab.ui.control.NumericEditField
        RUNFORFORCEPROFILESButton      matlab.ui.control.Button
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField    matlab.ui.control.NumericEditField
        RESETFORCEPROFILESButton       matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
        k1Label                        matlab.ui.control.Label
        k1EditField                    matlab.ui.control.NumericEditField
        k2Label                        matlab.ui.control.Label
        k2EditField                    matlab.ui.control.NumericEditField
        c1Label                        matlab.ui.control.Label
        c1EditField                    matlab.ui.control.NumericEditField
        c2Label                        matlab.ui.control.Label
        c2EditField                    matlab.ui.control.NumericEditField
        J1Label                        matlab.ui.control.Label
        J1EditField                    matlab.ui.control.NumericEditField
        J2Label                        matlab.ui.control.Label
        J2EditField                    matlab.ui.control.NumericEditField
        VALUESTOBEENTEREDLabel         matlab.ui.control.Label
        Length1EditFieldLabel          matlab.ui.control.Label
        Length1EditField               matlab.ui.control.NumericEditField
        Length2EditFieldLabel          matlab.ui.control.Label
        Length2EditField               matlab.ui.control.NumericEditField
        ChooseToDisplayDropDownLabel   matlab.ui.control.Label
        ChooseToDisplayDropDown        matlab.ui.control.DropDown
        SplineView                     matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxesNode13                   matlab.ui.control.UIAxes
        UIAxesNode12                   matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        force_array_x
        force_array_y
        time_step = 0.01
        number_of_iterations = 500
        force_profile_selection_x = "Sinousidal"
        force_profile_selection_y = "Sinousidal"
        magnitude_x = 10 %N
        magnitude_y = 10 %N
        time = 0  
        stored_x
        stored_y
        height_stored_x
        height_stored_y
        height_stored
        height_stored_node01
        k1value = 0.01;
        k2value = 0.01;
        c1value = 0.01;
        c2value = 0.01;
        J1value = 8.83 * 10^-3;
        J2value = 8.83 * 10^-3;
        length1 = 45 * 10^-2    ;   %FROM NODE 1 TO NODE 2;
        length2 = 55 * 10^-2    ;   %FROM NODE 2 TO NODE 3;
        coefs 
    end
    
    methods (Access = public)
        
        function results = updateforceprofiles(app)
            
            cla(app.UIAxes)
            cla(app.UIAxes2)
            
            if app.force_profile_selection_x == "Sinousidal"
                
                moment_x = [];
                
                for k=1:app.number_of_iterations
                    new_val = -pi + app.time_step*k;
                    moment_x = [moment_x,new_val];
                end
                
                size_moment_x= size(moment_x);
                number_of_seperate_forces_x = size_moment_x(2);
                app.force_array_x = [];
                
                for k_x=1:number_of_seperate_forces_x
                    app.force_array_x = [app.force_array_x, sin(moment_x(1,k_x))];
                end
                
                app.force_array_x = app.magnitude_x * app.force_array_x;
                
            elseif app.force_profile_selection_x == "Constant"
                
                moment_x = [];
                
                for i=1:app.number_of_iterations
                    moment_x = [moment_x,1];
                end
                
                app.force_array_x = app.magnitude_x * moment_x;
            end


            if app.force_profile_selection_y == "Sinousidal"
                moment_y = [];
                for k=1:app.number_of_iterations
                    new_val = -pi + app.time_step*k;
                    moment_y = [moment_y,new_val];
                end
                size_moment_y= size(moment_y);
                number_of_seperate_forces_y = size_moment_y(2);
                app.force_array_y = [];
                for k_y=1:number_of_seperate_forces_y
                    app.force_array_y= [app.force_array_y, sin(moment_y(1,k_y))];
                end 
                app.force_array_y = app.magnitude_y * app.force_array_y;
                
            elseif app.force_profile_selection_y == "Constant"
                moment_y = [];
                for i=1:app.number_of_iterations
                    moment_y = [moment_y,1];
                end    
                app.force_array_y = app.magnitude_y * moment_y;
            end

            
            plot(app.UIAxes,app.force_array_x)
            plot(app.UIAxes2,app.force_array_y)


        end
            
        
        function heightresults = updateHeightDifference(app)
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ForceProfileXSwitch
        function ForceProfileXSwitchValueChanged(app, event)
            app.force_profile_selection_x = app.ForceProfileXSwitch.Value;
            updateforceprofiles(app);
        end

        % Value changed function: ForceProfileYSwitch
        function ForceProfileYSwitchValueChanged(app, event)
            app.force_profile_selection_y = app.ForceProfileYSwitch.Value;
            updateforceprofiles(app);
        end

        % Value changed function: TimeStepEditField
        function TimeStepEditFieldValueChanged(app, event)
            app.time_step = app.TimeStepEditField.Value;
            updateforceprofiles(app);
        end

        % Button pushed function: RUNFORFORCEPROFILESButton
        function RUNFORFORCEPROFILESButtonPushed(app, event)
            updateforceprofiles(app);
        end

        % Value changed function: NumberofIterationsEditField
        function NumberofIterationsEditFieldValueChanged(app, event)
            app.number_of_iterations = app.NumberofIterationsEditField.Value;
            updateforceprofiles(app);
        end

        % Value changed function: ForceMagnifierforXSlider
        function ForceMagnifierforXSliderValueChanged(app, event)
            app.magnitude_x = app.ForceMagnifierforXSlider.Value;
            updateforceprofiles(app);
        end

        % Value changed function: ForceMagnifierforYSlider
        function ForceMagnifierforYSliderValueChanged(app, event)
            app.magnitude_y = app.ForceMagnifierforYSlider.Value;
            updateforceprofiles(app);
        end

        % Button pushed function: RESETFORCEPROFILESButton
        function RESETFORCEPROFILESButtonPushed(app, event)
            app.force_array_x = [];
            app.force_array_y = [];
            app.time_step = 0.01;
            app.number_of_iterations = 500;
            app.force_profile_selection_x = "Sinousidal";
            app.force_profile_selection_y = "Sinousidal";
            app.magnitude_x = 10; %N
            app.magnitude_y = 10; %N
            app.ForceMagnifierforXSlider.Value = 10;
            app.ForceMagnifierforYSlider.Value = 10;
            app.ForceProfileXSwitch.Value = "Sinousidal";
            app.ForceProfileYSwitch.Value = "Sinousidal";
            app.TimeStepEditField.Value = 0.01;
            app.NumberofIterationsEditField.Value = 500;
            cla(app.UIAxes);
            cla(app.UIAxes2);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            number_of_forces = size(app.force_array_x,2);
            
            k_values = [app.k1value,app.k2value];
            c_values = [app.c1value,app.c2value];
            J_values = [app.J1value,app.J2value];
            
            app.time = 0;
            
            for i=1:number_of_forces
                
                MomentVector_y = app.force_array_y(i);
                MomentVector_x = app.force_array_x(i);

                %app.time = app.time + app.time_step/10 ;
                app.time = app.time + app.time_step/10;
                
                if i==1
                    updated_initial_cond_y = [0 0 0 0];
                    updated_initial_cond_x = [0 0 0 0];
                end
                
                mass_spring_damp_func_y = @(t,y) ConnectedMassSpringDamperEquation (app.time,y,MomentVector_y,J_values,k_values,c_values);
                mass_spring_damp_func_x = @(t,x) ConnectedMassSpringDamperEquation (app.time,x,MomentVector_x,J_values,k_values,c_values);
                
                [app.time,result_y] = ode45(mass_spring_damp_func_y, [app.time-app.time_step/10:app.time_step/10:app.time], updated_initial_cond_y);
                [app.time,result_x] = ode45(mass_spring_damp_func_x, [app.time-app.time_step/10:app.time_step/10:app.time], updated_initial_cond_x);
                
                result_size_y = size(result_y);
                result_size_x = size(result_x);
                
                number_of_rows_y = result_size_y(1);
                number_of_rows_x = result_size_x(1);
                
                updated_initial_cond_y = [];
                updated_initial_cond_x = [];
                
                for j=1:4
                    updated_initial_cond_y(j) = result_y(number_of_rows_y,j);
                    updated_initial_cond_x(j) = result_x(number_of_rows_x,j);
                end
                
                app.stored_y = [app.stored_y; updated_initial_cond_y];
                app.stored_x = [app.stored_x; updated_initial_cond_x];
                
                height_y = app.length1 * sin(result_y(number_of_rows_y,1)) + app.length2 * (sin(result_y(number_of_rows_y,1)+result_y(number_of_rows_y,3)));
                height_x = app.length1 * sin(result_x(number_of_rows_x,1)) + app.length2 * (sin(result_x(number_of_rows_x,1)+result_x(number_of_rows_x,3)));
                
                height_y_node01 = app.length1 * sin(result_y(number_of_rows_y,1));
                height_x_node01 = app.length1 * sin(result_x(number_of_rows_x,1));
                
                
                app.height_stored_y = [ app.height_stored_y; height_y];
                app.height_stored_x = [ app.height_stored_x; height_x];
                app.height_stored = [app.height_stored,height_y+height_x ];
                app.height_stored_node01 = [app.height_stored_node01,height_y_node01+height_x_node01 ];
                
                
                plot(app.UIAxesNode12,i,height_y_node01+height_x_node01,"x")
                plot(app.UIAxesNode13,i,height_y+height_x,"x")
                hold(app.UIAxesNode12,"on")
                hold(app.UIAxesNode13,"on")
                plot(app.UIAxesNode12,[1:i],app.height_stored_node01(1:i))
                plot(app.UIAxesNode13,[1:i],app.height_stored(1:i))

                pause(app.time_step)
%                 
%                 syms a b c d e f g h 
%                 app.coefs = [];
%                 
%                 theta1_y = result_y(1);
%                 theta1_x = result_x(1);
%                 theta1 = theta1_y + theta1_x;
% 
%                 theta2_y = result_y(3);
%                 theta2_x = result_x(3);
%                 theta2 = theta2_y + theta2_x;
%                 
%                 x1 = 0;
%                 y1 = 0;
%                 x2 = app.length1 * cos(theta1) ;
%                 y2 = app.length1 * sin(theta1) ;
%                 x3 = app.length1 * cos(theta1) + app.length2 * cos(theta1+theta2);
%                 y3 = app.length1 * sin(theta1) + app.length2 * sin(theta1+theta2);
%                 
%                 eqns = [ a * x2^3 + b * x2^2 + c*x2 + d == y2,
%                      e * x2^3 + f * x2^2 + g*x2 + h == y2,
%                      a * x1^3 + b * x1^2 + c*x1 + d == y1,
%                      e * x3^3 + f * x3^2 + g*x3 + h == y3,
%                      3 * a * x2^2 + 2 * b * x2 + c == 3 * e * x2^2 + 2 * f * x2 + g,
%                      6 * a * x2 + 2 * b == 6 * e * x2 + 2 * f,
%                      6 * a * x1 + 2 * b == 0,
%                      6 * e * x3 + 2 * f == 0];
%                 
%                 vars = [a,b,c,d,e,f,g,h];
%             
%                 [Matrixformeqns,value] = equationsToMatrix(eqns,vars);
%             
%                 app.coefs = inv(Matrixformeqns) * value;
%                 
%                 hold(app.SplineView,"on")  ;
%                 
%                 q1 = app.coefs(1);
%                 q2 = app.coefs(2);
%                 q3 = app.coefs(3);
%                 q4 = app.coefs(4);
%                 q5 = app.coefs(5);
%                 q6 = app.coefs(6);
%                 q7 = app.coefs(7);
%                 q8 = app.coefs(8);
%                 
%                 fplot(app.SplineView, @(x) q1*x^3 + q2*x^2 + q3*x + q4, [x1,x2],'b');
%                 fplot(app.SplineView, @(x) q5*x^3 + q6*x^2 + q7*x + q8, [x2,x3],'r');
%             

                
            end
%             
%             plot(app.UIAxesNode12,app.height_stored_node01);
%             plot(app.UIAxesNode13,app.height_stored);
%       
 
        end

        % Value changed function: k1EditField
        function k1EditFieldValueChanged(app, event)
            app.k1value = app.k1EditField.Value;
            
        end

        % Value changed function: k2EditField
        function k2EditFieldValueChanged(app, event)
            app.k2value = app.k2EditField.Value;
            
        end

        % Value changed function: c1EditField
        function c1EditFieldValueChanged(app, event)
            app.c1value = app.c1EditField.Value;
            
        end

        % Value changed function: c2EditField
        function c2EditFieldValueChanged(app, event)
            app.c2value = app.c2EditField.Value;
            
        end

        % Value changed function: J1EditField
        function J1EditFieldValueChanged(app, event)
            app.J1value = app.J1EditField.Value;
            
        end

        % Value changed function: J2EditField
        function J2EditFieldValueChanged(app, event)
            app.J2value = app.J2EditField.Value;
            
        end

        % Value changed function: Length1EditField
        function Length1EditFieldValueChanged(app, event)
            app.length1 = app.Length1EditField.Value;
            
        end

        % Value changed function: Length2EditField
        function Length2EditFieldValueChanged(app, event)
            app.length2 = app.Length2EditField.Value;
            
        end

        % Button down function: UIAxesNode12
        function UIAxesNode12ButtonDown(app, event)
            
        end

        % Value changed function: ChooseToDisplayDropDown
        function ChooseToDisplayDropDownValueChanged(app, event)
            value = app.ChooseToDisplayDropDown.Value;
            if value == "Theta_1_y"
                plot(app.stored_y(:,1));
            elseif value == "Theta_2_y"
                plot(app.stored_y(:,3));
            elseif value == "Theta_1_x"
                plot(app.stored_x(:,1));
            elseif value == "Theta_2_x"
                plot(app.stored_x(:,3));
            elseif value == "Theta_1"
                plot(app.stored_y(:,1)+app.stored_x(:,1));
            elseif value == "Theta_2"
                plot(app.stored_y(:,3)+app.stored_x(:,3));   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ForceProfileXLabel = uilabel(app.GridLayout);
            app.ForceProfileXLabel.Text = 'Force Profile X';
            app.ForceProfileXSwitch = uiswitch(app.GridLayout, 'slider');
            app.ForceProfileXSwitch.Items = {'Sinousidal', 'Constant'};
            app.ForceProfileXSwitch.ValueChangedFcn = createCallbackFcn(app, @ForceProfileXSwitchValueChanged, true);
            app.ForceProfileXSwitch.Value = 'Sinousidal';
            app.ForceProfileYLabel = uilabel(app.GridLayout);
            app.ForceProfileYLabel.Text = 'Force Profile Y';
            app.ForceProfileYSwitch = uiswitch(app.GridLayout, 'slider');
            app.ForceProfileYSwitch.Items = {'Sinousidal', 'Constant'};
            app.ForceProfileYSwitch.ValueChangedFcn = createCallbackFcn(app, @ForceProfileYSwitchValueChanged, true);
            app.ForceProfileYSwitch.Value = 'Sinousidal';
            app.ForceMagnifierforXSliderLabel = uilabel(app.GridLayout);
            app.ForceMagnifierforXSliderLabel.Text = 'Force Magnifier for X';
            app.ForceMagnifierforXSlider = uislider(app.GridLayout);
            app.ForceMagnifierforXSlider.ValueChangedFcn = createCallbackFcn(app, @ForceMagnifierforXSliderValueChanged, true);
            app.ForceMagnifierforXSlider.Value = 10;
            app.ForceMagnifierforYSlider = uislider(app.GridLayout);
            app.ForceMagnifierforYSlider.ValueChangedFcn = createCallbackFcn(app, @ForceMagnifierforYSliderValueChanged, true);
            app.ForceMagnifierforYSlider.Value = 10;
            app.ForceMagnifierforYLabel = uilabel(app.GridLayout);
            app.ForceMagnifierforYLabel.Text = 'Force Magnifier for Y';
            app.TimeStepBetweenIterationsEditFieldLabel = uilabel(app.GridLayout);
            app.TimeStepBetweenIterationsEditFieldLabel.Text = 'Time Step Between Iterations';
            app.TimeStepEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStepEditFieldValueChanged, true);
            app.TimeStepEditField.Value = 0.01;
            app.RUNFORFORCEPROFILESButton = uibutton(app.GridLayout, 'push');
            app.RUNFORFORCEPROFILESButton.ButtonPushedFcn = createCallbackFcn(app, @RUNFORFORCEPROFILESButtonPushed, true);
            app.RUNFORFORCEPROFILESButton.Text = 'RUN FOR FORCE PROFILES';
            app.NumberofIterationsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofIterationsEditFieldValueChanged, true);
            app.NumberofIterationsEditField.Value = 500;
            app.RESETFORCEPROFILESButton = uibutton(app.GridLayout, 'push');
            app.RESETFORCEPROFILESButton.ButtonPushedFcn = createCallbackFcn(app, @RESETFORCEPROFILESButtonPushed, true);
            app.RESETFORCEPROFILESButton.Text = 'RESET FORCE PROFILES';
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.k1Label = uilabel(app.GridLayout);
            app.k1Label.Text = 'k1';
            app.k1EditField = uieditfield(app.GridLayout, 'numeric');
            app.k1EditField.ValueChangedFcn = createCallbackFcn(app, @k1EditFieldValueChanged, true);
            app.k1EditField.Value = 0.01;
            app.k2Label = uilabel(app.GridLayout);
            app.k2Label.Text = 'k2';
            app.k2EditField = uieditfield(app.GridLayout, 'numeric');
            app.k2EditField.ValueChangedFcn = createCallbackFcn(app, @k2EditFieldValueChanged, true);
            app.k2EditField.Value = 0.01;
            app.c1Label = uilabel(app.GridLayout);
            app.c1Label.Text = 'c1';
            app.c1EditField = uieditfield(app.GridLayout, 'numeric');
            app.c1EditField.ValueChangedFcn = createCallbackFcn(app, @c1EditFieldValueChanged, true);
            app.c1EditField.Value = 0.01;
            app.c2Label = uilabel(app.GridLayout);
            app.c2Label.Text = 'c2';
            app.c2EditField = uieditfield(app.GridLayout, 'numeric');
            app.c2EditField.ValueChangedFcn = createCallbackFcn(app, @c2EditFieldValueChanged, true);
            app.c2EditField.Value = 0.01;
            app.J1Label = uilabel(app.GridLayout);
            app.J1Label.Text = 'J1';
            app.J1EditField = uieditfield(app.GridLayout, 'numeric');
            app.J1EditField.ValueChangedFcn = createCallbackFcn(app, @J1EditFieldValueChanged, true);
            app.J1EditField.Value = 0.00883;
            app.J2Label = uilabel(app.GridLayout);
            app.J2Label.Text = 'J2';
            app.J2EditField = uieditfield(app.GridLayout, 'numeric');
            app.J2EditField.ValueChangedFcn = createCallbackFcn(app, @J2EditFieldValueChanged, true);
            app.J2EditField.Value = 0.00883;
            app.VALUESTOBEENTEREDLabel = uilabel(app.GridLayout);
            app.VALUESTOBEENTEREDLabel.Text = 'VALUES TO BE ENTERED';
            app.Length1EditFieldLabel = uilabel(app.GridLayout);
            app.Length1EditFieldLabel.Text = 'Length1';
            app.Length1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Length1EditField.ValueChangedFcn = createCallbackFcn(app, @Length1EditFieldValueChanged, true);
            app.Length1EditField.Value = 0.045;
            app.Length2EditFieldLabel = uilabel(app.GridLayout);
            app.Length2EditFieldLabel.Text = 'Length2';
            app.Length2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Length2EditField.ValueChangedFcn = createCallbackFcn(app, @Length2EditFieldValueChanged, true);
            app.Length2EditField.Value = 0.055;
            app.ChooseToDisplayDropDownLabel = uilabel(app.GridLayout);
            app.ChooseToDisplayDropDownLabel.Text = 'Choose To Display';
            app.ChooseToDisplayDropDown = uidropdown(app.GridLayout);
            app.ChooseToDisplayDropDown.Items = {'Options', 'Theta_1_y', 'Theta_2_y', 'Theta_1_x', 'Theta_2_x', 'Theta_1', 'Theta_2'};
            app.ChooseToDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseToDisplayDropDownValueChanged, true);
            app.ChooseToDisplayDropDown.Value = 'Options';
            app.SplineView = uiaxes(app.GridLayout);
            title(app.SplineView, 'Spline View')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Force Profile X')
            xlabel(app.UIAxes, 'Number of Iterations')
            ylabel(app.UIAxes, 'Force')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Force Profile Y')
            xlabel(app.UIAxes2, 'Number Of Iterations')
            ylabel(app.UIAxes2, 'Force')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxesNode13 = uiaxes(app.GridLayout);
            title(app.UIAxesNode13, 'Height Difference from Node 1 and 3')
            xlabel(app.UIAxesNode13, 'X')
            ylabel(app.UIAxesNode13, 'Y')
            zlabel(app.UIAxesNode13, 'Z')
            app.UIAxesNode12 = uiaxes(app.GridLayout);
            title(app.UIAxesNode12, 'Height Difference from Node 1 and 2')
            xlabel(app.UIAxesNode12, 'X')
            ylabel(app.UIAxesNode12, 'Y')
            zlabel(app.UIAxesNode12, 'Z')
            app.UIAxesNode12.ButtonDownFcn = createCallbackFcn(app, @UIAxesNode12ButtonDown, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.RUNFORFORCEPROFILESButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = softroboticsmassspringdamper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end